classdef mibDeepGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Figure                          matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        Mode                            matlab.ui.container.TabGroup
        Preprocess                      matlab.ui.container.Tab
        DirectorywithimagesandmodelsfortrainingLabel  matlab.ui.control.Label
        SelectOriginalTrainingImagesDir  matlab.ui.control.Button
        OriginalTrainingImagesDir       matlab.ui.control.EditField
        SelectResultingImagesDir        matlab.ui.control.Button
        ResultingImagesDir              matlab.ui.control.EditField
        DirectorywithresultingimagesLabel  matlab.ui.control.Label
        filenameextensionLabel          matlab.ui.control.Label
        ImageFilenameExtension          matlab.ui.control.DropDown
        CompressProcessedImages         matlab.ui.control.CheckBox
        DirectorywithimagesforpredictionLabel  matlab.ui.control.Label
        SelectOriginalPredictionImagesDir  matlab.ui.control.Button
        OriginalPredictionImagesDir     matlab.ui.control.EditField
        PreprocessforDropDownLabel      matlab.ui.control.Label
        PreprocessingMode               matlab.ui.control.DropDown
        NormalizeImages                 matlab.ui.control.CheckBox
        Bioformats                      matlab.ui.control.CheckBox
        FractionofimagesforvalidationEditFieldLabel  matlab.ui.control.Label
        ValidationFraction              matlab.ui.control.NumericEditField
        CompressProcessedModels         matlab.ui.control.CheckBox
        filenameextensionLabel_2        matlab.ui.control.Label
        ImageFilenameExtensionTraining  matlab.ui.control.DropDown
        BioformatsTraining              matlab.ui.control.CheckBox
        RandomgeneratorseedLabel        matlab.ui.control.Label
        RandomGeneratorSeed             matlab.ui.control.NumericEditField
        Train                           matlab.ui.container.Tab
        InputpatchsizeLabel             matlab.ui.control.Label
        T_InputPatchSize                matlab.ui.control.EditField
        NumberofclassesEditFieldLabel   matlab.ui.control.Label
        T_NumberOfClasses               matlab.ui.control.NumericEditField
        SegmentationlayerDropDownLabel  matlab.ui.control.Label
        T_SegmentationLayer             matlab.ui.control.DropDown
        CheckNetwork                    matlab.ui.control.Button
        EncoderdepthLabel               matlab.ui.control.Label
        T_EncoderDepth                  matlab.ui.control.NumericEditField
        TrainSettings                   matlab.ui.control.Button
        PatchesperimageLabel            matlab.ui.control.Label
        T_PatchesPerImage               matlab.ui.control.NumericEditField
        MiniBatchSizeLabel              matlab.ui.control.Label
        T_MiniBatchSize                 matlab.ui.control.NumericEditField
        NumFirstEncoderFiltersLabel     matlab.ui.control.Label
        T_NumFirstEncoderFilters        matlab.ui.control.NumericEditField
        FiltersizeLabel                 matlab.ui.control.Label
        T_FilterSize                    matlab.ui.control.NumericEditField
        AugmentationPanel               matlab.ui.container.Panel
        T_augmentation                  matlab.ui.control.CheckBox
        Augmentation2DSettings          matlab.ui.control.Button
        Augmentation3DSettings          matlab.ui.control.Button
        PaddingLabel                    matlab.ui.control.Label
        T_ConvolutionPadding            matlab.ui.control.DropDown
        InputLayerSettings              matlab.ui.control.Button
        T_ExportTrainingPlots           matlab.ui.control.CheckBox
        T_SaveProgress                  matlab.ui.control.CheckBox
        Predict                         matlab.ui.container.Tab
        P_OverlappingTiles              matlab.ui.control.CheckBox
        PreviewresultsPanel             matlab.ui.container.Panel
        LoadModels                      matlab.ui.control.Button
        LoadPredictions                 matlab.ui.control.Button
        PreviewResultsText              matlab.ui.control.Label
        PreviewResultsText2             matlab.ui.control.Label
        EvaluateSegmentation            matlab.ui.control.Button
        ExploreActivations              matlab.ui.control.Button
        Options                         matlab.ui.container.Tab
        ConfigfilesPanel                matlab.ui.container.Panel
        LoadButton                      matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        ConfigFilesLabel                matlab.ui.control.Label
        NetworkPanel                    matlab.ui.container.Panel
        NetworkfilenameEditFieldLabel   matlab.ui.control.Label
        NetworkFilename                 matlab.ui.control.EditField
        SelectNetwork                   matlab.ui.control.Button
        ArchitectureLabel               matlab.ui.control.Label
        Architecture                    matlab.ui.control.DropDown
        GPULabel                        matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        TrainButton                     matlab.ui.control.Button
        Help                            matlab.ui.control.Button
        PreprocessButton                matlab.ui.control.Button
        PredictButton                   matlab.ui.control.Button
    end

    
    properties (Access = public)
        winController % controller of the view % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, winController)
            app.winController = winController;
            app.Figure.Visible = 'off';
            app.ConfigFilesLabel.Text = sprintf('Config files are automatically created\nbefore training and include all\nDeep MIB settings but without\nthe trained network');
            app.PreviewResultsText.Text = sprintf('Use these buttons to preview predictions \nusing the currently active container of MIB.\nFor 3D networks only the first dataset will be shown');
            app.PreviewResultsText2.Text = sprintf('Press to evaluate results of the segmentation; requires\nground truth *.model files next to datasets for prediction');
        end

        % Close request function: Figure
        function FigureCloseRequest(app, event)
            app.winController.closeWindow();
        end

        % Button pushed function: Help
        function HelpButtonPushed(app, event)
            global mibPath;
            web(fullfile(mibPath, 'techdoc', 'html', 'ug_gui_menu_tools_deeplearning.html'), '-helpbrowser');
        end

        % Callback function: NetworkFilename, SelectNetwork
        function SelectNetworkButtonPushed(app, event)
            if strcmp(event.Source.Tag, 'NetworkFilename')
                app.winController.selectNetwork(event.Source.Value);   % callback from editbox change 
            else
                app.winController.selectNetwork();    
            end

        end

        % Value changed function: Architecture, 
        % CompressProcessedImages, CompressProcessedModels, 
        % ImageFilenameExtension, ImageFilenameExtensionTraining, 
        % NormalizeImages, P_OverlappingTiles, PreprocessingMode, 
        % RandomGeneratorSeed, T_ConvolutionPadding, 
        % T_EncoderDepth, T_ExportTrainingPlots, T_FilterSize, 
        % T_MiniBatchSize, T_NumFirstEncoderFilters, 
        % T_NumberOfClasses, T_PatchesPerImage, T_SaveProgress, 
        % T_SegmentationLayer, ValidationFraction
        function updateBatchOptFromGUI(app, event)
            app.winController.updateBatchOptFromGUI(event);
        end

        % Button pushed function: 
        % SelectOriginalPredictionImagesDir, 
        % SelectOriginalTrainingImagesDir, SelectResultingImagesDir
        function SelectDirerctories(app, event)
            app.winController.SelectDirerctories(event);
        end

        % Value changed function: OriginalPredictionImagesDir, 
        % OriginalTrainingImagesDir, ResultingImagesDir
        function ImageDirectoriesValueChanged(app, event)
            app.winController.ImageDirectoriesValueChanged(event);
            
        end

        % Button pushed function: PredictButton, PreprocessButton, 
        % TrainButton
        function StartButtonPushed(app, event)
            app.winController.Start(event);
        end

        % Button pushed function: CheckNetwork
        function CheckNetworkButtonPushed(app, event)
            app.winController.CheckNetwork();
        end

        % Button pushed function: TrainSettings
        function TrainSettingsButtonPushed(app, event)
            app.winController.setTrainingSettings();
        end

        % Selection change function: Mode
        function ModeSelectionChanged(app, event)
            selectedTab = app.Mode.SelectedTab;
            app.SelectNetwork.Enable = 'off';
            app.TrainButton.Enable = 'off';
            app.PredictButton.Enable = 'off';
            app.PreprocessButton.Enable = 'off';
            app.PreprocessButton.BackgroundColor = app.NetworkPanel.BackgroundColor;
            app.TrainButton.BackgroundColor = app.NetworkPanel.BackgroundColor;
            app.PredictButton.BackgroundColor = app.NetworkPanel.BackgroundColor;
            app.SelectNetwork.BackgroundColor = app.NetworkPanel.BackgroundColor;
            switch selectedTab.Tag
                case 'Preprocess'
                    app.PreprocessButton.Enable = 'on';
                    app.PreprocessButton.BackgroundColor = app.Preprocess.BackgroundColor;
                    app.NetworkFilename.Enable = 'off';
                case 'Train'
                    app.SelectNetwork.Enable = 'on';
                    app.SelectNetwork.BackgroundColor = app.Train.BackgroundColor;
                    app.TrainButton.BackgroundColor = app.Train.BackgroundColor;
                    app.TrainButton.Enable = 'on';
                    app.NetworkFilename.Enable = 'on';
                case 'Predict'
                    app.SelectNetwork.Enable = 'on';
                    app.SelectNetwork.BackgroundColor = app.Predict.BackgroundColor;
                    app.PredictButton.BackgroundColor = app.Predict.BackgroundColor;
                    app.PredictButton.Enable = 'on';
                    app.NetworkFilename.Enable = 'on';
            end
            app.winController.updateBatchOptFromGUI(event);
        end

        % Button pushed function: Augmentation2DSettings, 
        % Augmentation3DSettings
        function AugmentationSettingsButtonPushed(app, event)
            switch event.Source.Tag
                case 'Augmentation2DSettings'
                    app.winController.setAugmentation2DSettings();
                case 'Augmentation3DSettings'
                    app.winController.setAugmentation3DSettings();
            end
        end

        % Value changed function: T_augmentation
        function T_augmentationValueChanged(app, event)
            value = app.T_augmentation.Value;
            if value == 1
                app.Augmentation2DSettings.Enable = 'on';
                app.Augmentation3DSettings.Enable = 'on';
            else
                app.Augmentation2DSettings.Enable = 'off';
                app.Augmentation3DSettings.Enable = 'off';
            end
            app.winController.updateBatchOptFromGUI(event);
        end

        % Value changed function: T_InputPatchSize
        function T_InputPatchSizeValueChanged(app, event)
            value = app.T_InputPatchSize.Value;
            value = str2num(value); %#ok<ST2NM> 
            if numel(value) ~= 4
                errordlg(sprintf('!!! Error !!!\n\nPlease provide the input patch size as 4 numbers that define\nheight, width, depth, colors\n\nFor example:\n"32, 32, 1, 3" for 2D U-net of 3 color channel images\n"64, 64, 64, 1" for 3D U-net of 1 color channel images'), ...
                    'Wrong patch size');
                return;
            end
            app.winController.updateBatchOptFromGUI(event);
        end

        % Button pushed function: InputLayerSettings
        function InputLayerSettingsButtonPushed(app, event)
            app.winController.setInputLayerSettings();    
        end

        % Button pushed function: LoadPredictions
        function LoadPredictionsButtonPushed(app, event)
            app.winController.previewPredictions(); 
        end

        % Button pushed function: LoadModels
        function LoadModelsButtonPushed(app, event)
            app.winController.previewModels(); 
        end

        % Button pushed function: EvaluateSegmentation
        function EvaluateSegmentationButtonPushed(app, event)
            app.winController.EvaluateSegmentation(); 
        end

        % Button pushed function: ExploreActivations
        function ExploreActivationsButtonPushed(app, event)
            app.winController.ExploreActivations();
        end

        % Value changed function: Bioformats, BioformatsTraining
        function BioformatsValueChanged(app, event)
            app.winController.updateBatchOptFromGUI(event);
            app.winController.BioformatsCallback(event);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.winController.loadConfig();
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.winController.saveConfig();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Figure = uifigure('Visible', 'off');
            app.Figure.Name = 'DeepMIB';
            app.Figure.CloseRequestFcn = createCallbackFcn(app, @FigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.Figure);
            app.Mode = uitabgroup(app.GridLayout);
            app.Mode.SelectionChangedFcn = createCallbackFcn(app, @ModeSelectionChanged, true);
            app.Preprocess = uitab(app.Mode);
            app.Preprocess.Title = 'Directories and Preprocessing';
            app.DirectorywithimagesandmodelsfortrainingLabel = uilabel(app.Preprocess);
            app.DirectorywithimagesandmodelsfortrainingLabel.Text = 'Directory with images and models for training';
            app.SelectOriginalTrainingImagesDir = uibutton(app.Preprocess, 'push');
            app.SelectOriginalTrainingImagesDir.ButtonPushedFcn = createCallbackFcn(app, @SelectDirerctories, true);
            app.SelectOriginalTrainingImagesDir.Text = '...';
            app.OriginalTrainingImagesDir = uieditfield(app.Preprocess, 'text');
            app.OriginalTrainingImagesDir.ValueChangedFcn = createCallbackFcn(app, @ImageDirectoriesValueChanged, true);
            app.SelectResultingImagesDir = uibutton(app.Preprocess, 'push');
            app.SelectResultingImagesDir.ButtonPushedFcn = createCallbackFcn(app, @SelectDirerctories, true);
            app.SelectResultingImagesDir.Text = '...';
            app.ResultingImagesDir = uieditfield(app.Preprocess, 'text');
            app.ResultingImagesDir.ValueChangedFcn = createCallbackFcn(app, @ImageDirectoriesValueChanged, true);
            app.DirectorywithresultingimagesLabel = uilabel(app.Preprocess);
            app.DirectorywithresultingimagesLabel.Text = 'Directory with resulting images';
            app.filenameextensionLabel = uilabel(app.Preprocess);
            app.filenameextensionLabel.Text = 'filename extension';
            app.ImageFilenameExtension = uidropdown(app.Preprocess);
            app.ImageFilenameExtension.Items = {'TIF', 'Option 2', 'Option 3', 'Option 4'};
            app.ImageFilenameExtension.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.ImageFilenameExtension.Value = 'TIF';
            app.CompressProcessedImages = uicheckbox(app.Preprocess);
            app.CompressProcessedImages.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.CompressProcessedImages.Text = 'Compress processed images';
            app.DirectorywithimagesforpredictionLabel = uilabel(app.Preprocess);
            app.DirectorywithimagesforpredictionLabel.Text = 'Directory with images for prediction';
            app.SelectOriginalPredictionImagesDir = uibutton(app.Preprocess, 'push');
            app.SelectOriginalPredictionImagesDir.ButtonPushedFcn = createCallbackFcn(app, @SelectDirerctories, true);
            app.SelectOriginalPredictionImagesDir.Text = '...';
            app.OriginalPredictionImagesDir = uieditfield(app.Preprocess, 'text');
            app.OriginalPredictionImagesDir.ValueChangedFcn = createCallbackFcn(app, @ImageDirectoriesValueChanged, true);
            app.PreprocessforDropDownLabel = uilabel(app.Preprocess);
            app.PreprocessforDropDownLabel.Text = 'Preprocess for';
            app.PreprocessingMode = uidropdown(app.Preprocess);
            app.PreprocessingMode.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.NormalizeImages = uicheckbox(app.Preprocess);
            app.NormalizeImages.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.NormalizeImages.Enable = 'off';
            app.NormalizeImages.Text = 'Normalize images';
            app.Bioformats = uicheckbox(app.Preprocess);
            app.Bioformats.ValueChangedFcn = createCallbackFcn(app, @BioformatsValueChanged, true);
            app.Bioformats.Text = 'Bio';
            app.FractionofimagesforvalidationEditFieldLabel = uilabel(app.Preprocess);
            app.FractionofimagesforvalidationEditFieldLabel.Text = 'Fraction of images for validation';
            app.ValidationFraction = uieditfield(app.Preprocess, 'numeric');
            app.ValidationFraction.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.ValidationFraction.Value = 0.25;
            app.CompressProcessedModels = uicheckbox(app.Preprocess);
            app.CompressProcessedModels.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.CompressProcessedModels.Text = 'Compress processed models';
            app.filenameextensionLabel_2 = uilabel(app.Preprocess);
            app.filenameextensionLabel_2.Text = 'filename extension';
            app.ImageFilenameExtensionTraining = uidropdown(app.Preprocess);
            app.ImageFilenameExtensionTraining.Items = {'TIF', 'Option 2', 'Option 3', 'Option 4'};
            app.ImageFilenameExtensionTraining.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.ImageFilenameExtensionTraining.Value = 'TIF';
            app.BioformatsTraining = uicheckbox(app.Preprocess);
            app.BioformatsTraining.ValueChangedFcn = createCallbackFcn(app, @BioformatsValueChanged, true);
            app.BioformatsTraining.Text = 'Bio';
            app.RandomgeneratorseedLabel = uilabel(app.Preprocess);
            app.RandomgeneratorseedLabel.Text = 'Random generator seed';
            app.RandomGeneratorSeed = uieditfield(app.Preprocess, 'numeric');
            app.RandomGeneratorSeed.Limits = [0 Inf];
            app.RandomGeneratorSeed.RoundFractionalValues = 'on';
            app.RandomGeneratorSeed.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.Train = uitab(app.Mode);
            app.Train.Title = 'Train';
            app.InputpatchsizeLabel = uilabel(app.Train);
            app.InputpatchsizeLabel.Text = 'Input patch size';
            app.T_InputPatchSize = uieditfield(app.Train, 'text');
            app.T_InputPatchSize.ValueChangedFcn = createCallbackFcn(app, @T_InputPatchSizeValueChanged, true);
            app.T_InputPatchSize.Value = '64 64 32';
            app.NumberofclassesEditFieldLabel = uilabel(app.Train);
            app.NumberofclassesEditFieldLabel.Text = 'Number of classes';
            app.T_NumberOfClasses = uieditfield(app.Train, 'numeric');
            app.T_NumberOfClasses.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.T_NumberOfClasses.Value = 3;
            app.SegmentationlayerDropDownLabel = uilabel(app.Train);
            app.SegmentationlayerDropDownLabel.Text = 'Segmentation layer';
            app.T_SegmentationLayer = uidropdown(app.Train);
            app.T_SegmentationLayer.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.CheckNetwork = uibutton(app.Train, 'push');
            app.CheckNetwork.ButtonPushedFcn = createCallbackFcn(app, @CheckNetworkButtonPushed, true);
            app.CheckNetwork.Text = 'Check network';
            app.EncoderdepthLabel = uilabel(app.Train);
            app.EncoderdepthLabel.Text = 'Encoder depth';
            app.T_EncoderDepth = uieditfield(app.Train, 'numeric');
            app.T_EncoderDepth.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.T_EncoderDepth.Value = 3;
            app.TrainSettings = uibutton(app.Train, 'push');
            app.TrainSettings.ButtonPushedFcn = createCallbackFcn(app, @TrainSettingsButtonPushed, true);
            app.TrainSettings.Icon = 'settings.png';
            app.TrainSettings.Tooltip = {'Define training parameters'; ''};
            app.TrainSettings.Text = 'Training';
            app.PatchesperimageLabel = uilabel(app.Train);
            app.PatchesperimageLabel.Text = 'Patches per image';
            app.T_PatchesPerImage = uieditfield(app.Train, 'numeric');
            app.T_PatchesPerImage.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.T_PatchesPerImage.Value = 32;
            app.MiniBatchSizeLabel = uilabel(app.Train);
            app.MiniBatchSizeLabel.Text = 'Mini Batch Size';
            app.T_MiniBatchSize = uieditfield(app.Train, 'numeric');
            app.T_MiniBatchSize.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.T_MiniBatchSize.Value = 4;
            app.NumFirstEncoderFiltersLabel = uilabel(app.Train);
            app.NumFirstEncoderFiltersLabel.Text = 'NumFirstEncoderFilters';
            app.T_NumFirstEncoderFilters = uieditfield(app.Train, 'numeric');
            app.T_NumFirstEncoderFilters.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.T_NumFirstEncoderFilters.Value = 32;
            app.FiltersizeLabel = uilabel(app.Train);
            app.FiltersizeLabel.Text = 'Filter size';
            app.T_FilterSize = uieditfield(app.Train, 'numeric');
            app.T_FilterSize.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.T_FilterSize.Value = 3;
            app.AugmentationPanel = uipanel(app.Train);
            app.AugmentationPanel.Title = 'Augmentation';
            app.T_augmentation = uicheckbox(app.AugmentationPanel);
            app.T_augmentation.ValueChangedFcn = createCallbackFcn(app, @T_augmentationValueChanged, true);
            app.T_augmentation.Tooltip = {'enable data augmentation'};
            app.T_augmentation.Text = 'augmentation';
            app.Augmentation2DSettings = uibutton(app.AugmentationPanel, 'push');
            app.Augmentation2DSettings.ButtonPushedFcn = createCallbackFcn(app, @AugmentationSettingsButtonPushed, true);
            app.Augmentation2DSettings.Icon = 'settings.png';
            app.Augmentation2DSettings.Tooltip = {'set settings for 2D augmentation'};
            app.Augmentation2DSettings.Text = '2D';
            app.Augmentation3DSettings = uibutton(app.AugmentationPanel, 'push');
            app.Augmentation3DSettings.ButtonPushedFcn = createCallbackFcn(app, @AugmentationSettingsButtonPushed, true);
            app.Augmentation3DSettings.Icon = 'settings.png';
            app.Augmentation3DSettings.Tooltip = {'set settings for 3D augmentation'; ''};
            app.Augmentation3DSettings.Text = '3D';
            app.PaddingLabel = uilabel(app.Train);
            app.PaddingLabel.Text = 'Padding';
            app.T_ConvolutionPadding = uidropdown(app.Train);
            app.T_ConvolutionPadding.Items = {'same', 'valid'};
            app.T_ConvolutionPadding.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.T_ConvolutionPadding.Value = 'same';
            app.InputLayerSettings = uibutton(app.Train, 'push');
            app.InputLayerSettings.ButtonPushedFcn = createCallbackFcn(app, @InputLayerSettingsButtonPushed, true);
            app.InputLayerSettings.Icon = 'settings.png';
            app.InputLayerSettings.Tooltip = {'Define training parameters'; ''};
            app.InputLayerSettings.Text = 'Input layer';
            app.T_ExportTrainingPlots = uicheckbox(app.Train);
            app.T_ExportTrainingPlots.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.T_ExportTrainingPlots.Text = 'Export training plots';
            app.T_SaveProgress = uicheckbox(app.Train);
            app.T_SaveProgress.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.T_SaveProgress.Text = 'Save progress after each epoch';
            app.Predict = uitab(app.Mode);
            app.Predict.Title = 'Predict';
            app.P_OverlappingTiles = uicheckbox(app.Predict);
            app.P_OverlappingTiles.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.P_OverlappingTiles.Text = 'Overlapping tiles';
            app.PreviewresultsPanel = uipanel(app.Predict);
            app.PreviewresultsPanel.Title = 'Preview results';
            app.LoadModels = uibutton(app.PreviewresultsPanel, 'push');
            app.LoadModels.ButtonPushedFcn = createCallbackFcn(app, @LoadModelsButtonPushed, true);
            app.LoadModels.Text = 'Load images and models';
            app.LoadPredictions = uibutton(app.PreviewresultsPanel, 'push');
            app.LoadPredictions.ButtonPushedFcn = createCallbackFcn(app, @LoadPredictionsButtonPushed, true);
            app.LoadPredictions.Text = 'Load prediction scores';
            app.PreviewResultsText = uilabel(app.PreviewresultsPanel);
            app.PreviewResultsText.Text = 'Use buttons below to preview predictions.';
            app.PreviewResultsText2 = uilabel(app.PreviewresultsPanel);
            app.PreviewResultsText2.Text = 'Evaluate segmentation';
            app.EvaluateSegmentation = uibutton(app.PreviewresultsPanel, 'push');
            app.EvaluateSegmentation.ButtonPushedFcn = createCallbackFcn(app, @EvaluateSegmentationButtonPushed, true);
            app.EvaluateSegmentation.Text = 'Evaluate segmentation';
            app.ExploreActivations = uibutton(app.Predict, 'push');
            app.ExploreActivations.ButtonPushedFcn = createCallbackFcn(app, @ExploreActivationsButtonPushed, true);
            app.ExploreActivations.Text = 'Explore activations';
            app.Options = uitab(app.Mode);
            app.Options.Title = 'Options';
            app.ConfigfilesPanel = uipanel(app.Options);
            app.ConfigfilesPanel.Title = 'Config files';
            app.LoadButton = uibutton(app.ConfigfilesPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveButton = uibutton(app.ConfigfilesPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ConfigFilesLabel = uilabel(app.ConfigfilesPanel);
            app.NetworkPanel = uipanel(app.GridLayout);
            app.NetworkPanel.Title = 'Network';
            app.NetworkfilenameEditFieldLabel = uilabel(app.NetworkPanel);
            app.NetworkfilenameEditFieldLabel.Text = 'Network filename:';
            app.NetworkFilename = uieditfield(app.NetworkPanel, 'text');
            app.NetworkFilename.ValueChangedFcn = createCallbackFcn(app, @SelectNetworkButtonPushed, true);
            app.NetworkFilename.Enable = 'off';
            app.SelectNetwork = uibutton(app.NetworkPanel, 'push');
            app.SelectNetwork.ButtonPushedFcn = createCallbackFcn(app, @SelectNetworkButtonPushed, true);
            app.SelectNetwork.Enable = 'off';
            app.SelectNetwork.Text = '...';
            app.ArchitectureLabel = uilabel(app.NetworkPanel);
            app.ArchitectureLabel.Text = 'Architecture:';
            app.Architecture = uidropdown(app.NetworkPanel);
            app.Architecture.Items = {'3D U-net', '2D U-net'};
            app.Architecture.ValueChangedFcn = createCallbackFcn(app, @updateBatchOptFromGUI, true);
            app.Architecture.Value = '3D U-net';
            app.GPULabel = uilabel(app.NetworkPanel);
            app.GPULabel.Text = 'GPU: ';
            app.Panel_2 = uipanel(app.GridLayout);
            app.TrainButton = uibutton(app.Panel_2, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.TrainButton.Enable = 'off';
            app.TrainButton.Text = 'Train';
            app.Help = uibutton(app.Panel_2, 'push');
            app.Help.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.Help.Text = 'Help';
            app.PreprocessButton = uibutton(app.Panel_2, 'push');
            app.PreprocessButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.PreprocessButton.Text = 'Preprocess';
            app.PredictButton = uibutton(app.Panel_2, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.PredictButton.Enable = 'off';
            app.PredictButton.Text = 'Predict';
            % Show the figure after all components are created
            app.Figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mibDeepGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Figure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Figure)
        end
    end
end